classdef projectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RobotToControlSwitch         matlab.ui.control.Switch
        RobotToControlSwitchLabel    matlab.ui.control.Label
        VzEditFieldLabel_2           matlab.ui.control.Label
        VyEditFieldLabel_2           matlab.ui.control.Label
        VxEditFieldLabel_2           matlab.ui.control.Label
        VzEditField_2                matlab.ui.control.NumericEditField
        VyEditField_2                matlab.ui.control.NumericEditField
        VxEditField_2                matlab.ui.control.NumericEditField
        VzEditField                  matlab.ui.control.NumericEditField
        VzEditFieldLabel             matlab.ui.control.Label
        VyEditField                  matlab.ui.control.NumericEditField
        VyEditFieldLabel             matlab.ui.control.Label
        VxEditField                  matlab.ui.control.NumericEditField
        VxEditFieldLabel             matlab.ui.control.Label
        WxButton_2                   matlab.ui.control.Button
        WyButton_2                   matlab.ui.control.Button
        WyButton                     matlab.ui.control.Button
        WxButton                     matlab.ui.control.Button
        WzButton_2                   matlab.ui.control.Button
        WzButton                     matlab.ui.control.Button
        VxButton_2                   matlab.ui.control.Button
        VyButton_2                   matlab.ui.control.Button
        VyButton                     matlab.ui.control.Button
        VxButton                     matlab.ui.control.Button
        VzButton_2                   matlab.ui.control.Button
        VzButton                     matlab.ui.control.Button
        CartesianControlLabel        matlab.ui.control.Label
        ModeSwitch                   matlab.ui.control.Switch
        ModeSwitchLabel              matlab.ui.control.Label
        STARTButton                  matlab.ui.control.Button
        DisengageButton              matlab.ui.control.Button
        ResumeButton                 matlab.ui.control.Button
        eSTOPButton                  matlab.ui.control.Button
        q7Slider                     matlab.ui.control.Slider
        q6Slider                     matlab.ui.control.Slider
        q5Slider                     matlab.ui.control.Slider
        q4Slider                     matlab.ui.control.Slider
        q3Slider                     matlab.ui.control.Slider
        q2Slider                     matlab.ui.control.Slider
        q1Slider                     matlab.ui.control.Slider
        dZSpinner                    matlab.ui.control.Spinner
        dZSpinnerLabel               matlab.ui.control.Label
        dYSpinner                    matlab.ui.control.Spinner
        dYSpinnerLabel               matlab.ui.control.Label
        dXSpinner                    matlab.ui.control.Spinner
        dXSpinnerLabel               matlab.ui.control.Label
        RollSpinner                  matlab.ui.control.Spinner
        RollSpinnerLabel             matlab.ui.control.Label
        PitchSpinner                 matlab.ui.control.Spinner
        PitchSpinnerLabel            matlab.ui.control.Label
        YawSpinner                   matlab.ui.control.Spinner
        YawSpinnerLabel              matlab.ui.control.Label
        JointPositionControlLabel    matlab.ui.control.Label
        JointControlCheckBox         matlab.ui.control.CheckBox
        EndEffectorControlCheckBox   matlab.ui.control.CheckBox
        TrajectoryGeneratorDropDown  matlab.ui.control.DropDown
        EndEffectorStepLabel         matlab.ui.control.Label
        ExecuteButton                matlab.ui.control.Button
        PlanButton                   matlab.ui.control.Button
        q7SliderLabel                matlab.ui.control.Label
        q6SliderLabel                matlab.ui.control.Label
        q5SliderLabel                matlab.ui.control.Label
        q4SliderLabel                matlab.ui.control.Label
        q3SliderLabel                matlab.ui.control.Label
        q2SliderLabel                matlab.ui.control.Label
        q1SliderLabel                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       TM5;
       UR3e;
    end
    
    methods (Access = private)
        
        function tm5traj = traj1(app,TM5)
            %using quintic traj gen for more complex movements to minimise jerk
            tm5traj1 = app.TM5.quintic_ikcon(app.TM5,app.TM5.model.getpos(),0,app.TM5.model.fkine([0    0.1745   -0.7854    0.1745    0.1745    0.1745    3.1416]),50);
            
            tm5traj2 = app.TM5.trap_ikcon(app.TM5,app.TM5.model.getpos(),0,TM5.model.fkine([-0.2000    0.1745   -0.7854    0.5236    0.1745    0.5236    3.1416]),50);
            
            tm5traj3 = TM5.quintic_ikcon(TM5,TM5.model.getpos(),0,TM5.model.fkine([-0.2000    0.3427   -0.7997    1.1424    2.7418   -1.5422    3.1416]),50);
            
            tm5traj4 = TM5.quintic_ikcon(TM5,TM5.model.getpos(),0,TM5.model.fkine([-0.2000    0.3319   -0.8866    1.8326    2.1681   -1.1947    3.1416]),25);
            
            tm5traj5 = TM5.quintic_ikcon(TM5,TM5.model.getpos(),0,TM5.model.fkine([-0.2000    0.3319   -0.9599    1.8326    2.2689   -1.2392    3.1416]),10);
            
            tm5traj = [tm5traj1;tm5traj2;tm5traj3;tm5traj4;tm5traj5];
            
            %Move to Print Bed and pick up


        end
        
        function tm5traj = traj2(app,TM5)
            
            tm5traj1 = TM5.quintic_ikcon(TM5,TM5.model.getpos(),0,TM5.model.fkine([-0.2000    0.3319   -0.9599    1.8326    2.2689   -1.2392    3.1416]),25);
            
            tm5traj2 = TM5.quintic_ikcon(TM5,TM5.model.getpos(),0,TM5.model.fkine([-0.2000    0.3319   -0.8866    1.8326    2.1681   -1.1947    3.1416]),10);
            
            %proceed towards UR3e
            
            tm5traj3 = TM5.trap_ikcon(TM5,TM5.model.getpos(),0,TM5.model.fkine([ -0.2520    1.2427   -1.0751    1.3090    2.7336   -1.8326    3.1416]),50);
            
            tm5traj4 = TM5.trap_ikcon(TM5,TM5.model.getpos(),0,TM5.model.fkine([ -0.1888    1.3369   -0.8238    1.5603    2.5451   -1.8326    3.1416]),50);
            
            %Return the TM5 toward the printers, activate the UR3e
            
            tm5traj5 = TM5.quintic_ikcon(TM5,TM5.model.getpos(),0,TM5.model.fkine([-0.2000    0.1745   -0.7854    0.5236    0.1745    0.5236    3.1416]),100);
            
            tm5traj = [tm5traj1;tm5traj2;tm5traj3;tm5traj4;tm5traj5];
        end
        
        function tm5traj,UR3etraj = traj3(app,TM5,UR3e)
            
            %Return the TM5 toward the printers, activate the UR3e
            tm5traj = TM5.quintic_ikcon(TM5,TM5.model.getpos(),0,TM5.model.fkine([-0.2000    0.1745   -0.7854    0.5236    0.1745    0.5236    3.1416]),400);
            
            UR3etraj = 'yep';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            location = {
                        [0,0,0]
                        [1.25,1,0.324] %Bench_Omran %BECAUSE OF ROTATION OF Z THIS ONE HAS NEGATIVE BUT IT IS [1.25,1,0.324]
                        [1.25,2,0.324]   %Bench_Print
                        [-1.25,1,0.324]  %Bench_UR3E
            
                        [-0.5,0.603,1.445] %Bench_UR3E_BinR1
                        [-1,0.603,1.445] %Bench_UR3E_BinR2
                        [-0.5,1.397,1.445] %Bench_UR3E_BinL1
                        [-1,1.397,1.445] %Bench_UR3E_BinL2
            
                        [0.3,1.85,1.343]   %Bench_Print_Printer1
                        [.9,1.85,1.343]   %Bench_Print_Printer2
                        [1.5,1.85,1.343]   %Bench_Print_Printer3
                        
                       }; 
            
            hold on
            PlaceObject('Garage.PLY',location{1});
           
            PlaceObject('Bench.PLY',location{2});
            PlaceObject('Bench.PLY',location{3});
            PlaceObject('WBench.PLY',location{4});
            
            PlaceObject('BinR.ply',location{5});
            PlaceObject('BinR.ply',location{6});
            PlaceObject('BinL.ply',location{7});
            PlaceObject('BinL.ply',location{8});
            
            printer_1 = printerSpawn(location{9});
            printer_2 = printerSpawn(location{10});
            printer_3 = printerSpawn(location{11});
            
            app.TM5 = LinearTM5();
            app.UR3e = ur3e_modified();

            urEndPose = app.UR3e.model.fkine(app.UR3e.model.getpos());
            gripL = g_2F85;
            gripR = g_2F85;
            
            gripR.model.base = urEndPose.T*trotx(-pi/2)*trotz(-pi);
            gripL.model.base = urEndPose.T*trotx(pi/2);
            gripL.model.animate([0,0,0]);
            gripR.model.animate([0,0,0]);
            
            pb1 = printBed();
            
            pb1.bedModel{1}.base = transl(.275,1.806,1.354);
            
            pb1.UpdateModel();


        end

        % Button pushed function: PlanButton
        function PlanButtonPushed(app, event)
           if app.EndEffectorControlCheckBox == 0


               if app.RobotToControlSwitch == 'Off'
                   %UR3e End-Effector Controlled


               elseif app.RobotToControlSwitch == 'ON'
                   %TM5 End-Effector Controlled
               end
           elseif app.JointControlCheckBox ==   1
               if app.RobotToControlSwitch == 'Off'
                   %UR3e Joint Controlled

               elseif app.RobotToControlSwitch == 'ON'
                   %TM5 Joint Controlled
               end
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Environment')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'Rail';
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q7SliderLabel = uilabel(app.UIFigure);
            app.q7SliderLabel.Text = 'q7';
            app.PlanButton = uibutton(app.UIFigure, 'push');
            app.PlanButton.ButtonPushedFcn = createCallbackFcn(app, @PlanButtonPushed, true);
            app.PlanButton.Text = 'Plan';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.Text = 'Execute';
            app.EndEffectorStepLabel = uilabel(app.UIFigure);
            app.EndEffectorStepLabel.Text = 'End-Effector Step';
            app.TrajectoryGeneratorDropDown = uidropdown(app.UIFigure);
            app.TrajectoryGeneratorDropDown.Items = {'Trapezoidal ikine', 'Trapezoidal ikcon', 'Quintic ikine', 'Quintic ikcon'};
            app.TrajectoryGeneratorDropDown.Value = 'Trapezoidal ikcon';
            app.EndEffectorControlCheckBox = uicheckbox(app.UIFigure);
            app.EndEffectorControlCheckBox.Text = 'End Effector Control';
            app.JointControlCheckBox = uicheckbox(app.UIFigure);
            app.JointControlCheckBox.Text = 'Joint Control';
            app.JointPositionControlLabel = uilabel(app.UIFigure);
            app.JointPositionControlLabel.Text = 'Joint Position Control';
            app.YawSpinnerLabel = uilabel(app.UIFigure);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.YawSpinner = uispinner(app.UIFigure);
            app.PitchSpinnerLabel = uilabel(app.UIFigure);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.PitchSpinner = uispinner(app.UIFigure);
            app.RollSpinnerLabel = uilabel(app.UIFigure);
            app.RollSpinnerLabel.Text = 'Roll';
            app.RollSpinner = uispinner(app.UIFigure);
            app.dXSpinnerLabel = uilabel(app.UIFigure);
            app.dXSpinnerLabel.Text = 'dX';
            app.dXSpinner = uispinner(app.UIFigure);
            app.dYSpinnerLabel = uilabel(app.UIFigure);
            app.dYSpinnerLabel.Text = 'dY';
            app.dYSpinner = uispinner(app.UIFigure);
            app.dZSpinnerLabel = uilabel(app.UIFigure);
            app.dZSpinnerLabel.Text = 'dZ';
            app.dZSpinner = uispinner(app.UIFigure);
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-0.8 -0.01];
            app.q1Slider.Value = -0.01;
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-270 270];
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-180 180];
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-180 180];
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-180 180];
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-180 180];
            app.q7Slider = uislider(app.UIFigure);
            app.q7Slider.Limits = [-270 270];
            app.eSTOPButton = uibutton(app.UIFigure, 'push');
            app.eSTOPButton.Text = 'eSTOP';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.Text = 'Resume';
            app.DisengageButton = uibutton(app.UIFigure, 'push');
            app.DisengageButton.Text = 'Disengage';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.Text = 'START';
            app.ModeSwitchLabel = uilabel(app.UIFigure);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'Manual', 'Automatic'};
            app.ModeSwitch.Value = 'Manual';
            app.CartesianControlLabel = uilabel(app.UIFigure);
            app.CartesianControlLabel.Text = 'Cartesian Control';
            app.VzButton = uibutton(app.UIFigure, 'push');
            app.VzButton.Text = 'Vz-';
            app.VzButton_2 = uibutton(app.UIFigure, 'push');
            app.VzButton_2.Text = 'Vz+';
            app.VxButton = uibutton(app.UIFigure, 'push');
            app.VxButton.Text = 'Vx+';
            app.VyButton = uibutton(app.UIFigure, 'push');
            app.VyButton.Text = 'Vy-';
            app.VyButton_2 = uibutton(app.UIFigure, 'push');
            app.VyButton_2.Text = 'Vy+';
            app.VxButton_2 = uibutton(app.UIFigure, 'push');
            app.VxButton_2.Text = 'Vx-';
            app.WzButton = uibutton(app.UIFigure, 'push');
            app.WzButton.Text = 'Wz-';
            app.WzButton_2 = uibutton(app.UIFigure, 'push');
            app.WzButton_2.Text = 'Wz+';
            app.WxButton = uibutton(app.UIFigure, 'push');
            app.WxButton.Text = 'Wx+';
            app.WyButton = uibutton(app.UIFigure, 'push');
            app.WyButton.Text = 'Wy-';
            app.WyButton_2 = uibutton(app.UIFigure, 'push');
            app.WyButton_2.Text = 'Wy+';
            app.WxButton_2 = uibutton(app.UIFigure, 'push');
            app.WxButton_2.Text = 'Wx-';
            app.VxEditFieldLabel = uilabel(app.UIFigure);
            app.VxEditFieldLabel.Text = 'Vx';
            app.VxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VyEditFieldLabel = uilabel(app.UIFigure);
            app.VyEditFieldLabel.Text = 'Vy';
            app.VyEditField = uieditfield(app.UIFigure, 'numeric');
            app.VzEditFieldLabel = uilabel(app.UIFigure);
            app.VzEditFieldLabel.Text = 'Vz';
            app.VzEditField = uieditfield(app.UIFigure, 'numeric');
            app.VxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VyEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VxEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VxEditFieldLabel_2.Text = 'Wx';
            app.VyEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VyEditFieldLabel_2.Text = 'Wy';
            app.VzEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VzEditFieldLabel_2.Text = 'Wz';
            app.RobotToControlSwitchLabel = uilabel(app.UIFigure);
            app.RobotToControlSwitchLabel.Text = 'Robot To Control';
            app.RobotToControlSwitch = uiswitch(app.UIFigure, 'slider');
            app.RobotToControlSwitch.Items = {'UR3e', 'TM5'};
            app.RobotToControlSwitch.Value = 'UR3e';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end